classdef FTIR_data_analysis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        CurrentdataLabel               matlab.ui.control.Label
        CurrentdataTextArea            matlab.ui.control.TextArea
        DiffusionCoefficientm2sLabel   matlab.ui.control.Label
        DiffusionCoefficientm2sTextArea  matlab.ui.control.TextArea
        OtherparametersTextAreaLabel   matlab.ui.control.Label
        OtherparametersTextArea        matlab.ui.control.TextArea
        TabGroup                       matlab.ui.container.TabGroup
        FormatDataTab                  matlab.ui.container.Tab
        TextArea_2                     matlab.ui.control.TextArea
        SampleVolumeLEditField_2Label_2  matlab.ui.control.Label
        SampleVolumeLEditField         matlab.ui.control.NumericEditField
        SpacerSizemEditField_2Label_2  matlab.ui.control.Label
        SpacerSizemEditField           matlab.ui.control.NumericEditField
        TimeIntervalsEditField_2Label_2  matlab.ui.control.Label
        TimeIntervalsEditField         matlab.ui.control.NumericEditField
        RadiusmEditField_2Label        matlab.ui.control.Label
        RadiusmEditField               matlab.ui.control.NumericEditField
        DateDatePickerLabel            matlab.ui.control.Label
        DateDatePicker                 matlab.ui.control.DatePicker
        TodayButton                    matlab.ui.control.Button
        YourNameEditFieldLabel         matlab.ui.control.Label
        YourNameEditField              matlab.ui.control.EditField
        SampleNameEditFieldLabel       matlab.ui.control.Label
        SampleNameEditField            matlab.ui.control.EditField
        GasLineFitTab                  matlab.ui.container.Tab
        SpectrumEditFieldLabel         matlab.ui.control.Label
        SpectrumEditField              matlab.ui.control.NumericEditField
        centerEditFieldLabel           matlab.ui.control.Label
        centerEditField                matlab.ui.control.NumericEditField
        wgEditFieldLabel               matlab.ui.control.Label
        wgEditField                    matlab.ui.control.NumericEditField
        wlEditFieldLabel               matlab.ui.control.Label
        wlEditField                    matlab.ui.control.NumericEditField
        a1EditFieldLabel               matlab.ui.control.Label
        a1EditField                    matlab.ui.control.NumericEditField
        a2EditFieldLabel               matlab.ui.control.Label
        a2EditField                    matlab.ui.control.NumericEditField
        a3EditFieldLabel               matlab.ui.control.Label
        a3EditField                    matlab.ui.control.NumericEditField
        c0EditFieldLabel               matlab.ui.control.Label
        c0EditField                    matlab.ui.control.NumericEditField
        c1EditFieldLabel               matlab.ui.control.Label
        c1EditField                    matlab.ui.control.NumericEditField
        InitalFitGuessLabel            matlab.ui.control.Label
        TestParametersButton           matlab.ui.control.Button
        DoFitButton                    matlab.ui.control.Button
        TextArea                       matlab.ui.control.TextArea
        UIAxes3                        matlab.ui.control.UIAxes
        CurveFittingTab                matlab.ui.container.Tab
        TryFitButton                   matlab.ui.control.Button
        TestGuessButton                matlab.ui.control.Button
        GenerateTimeAxisButton         matlab.ui.control.Button
        FitMethodDropDownLabel         matlab.ui.control.Label
        FitMethodDropDown              matlab.ui.control.DropDown
        RelevantParametersfordiffusionmPanel  matlab.ui.container.Panel
        DLabel                         matlab.ui.control.Label
        SPLabel                        matlab.ui.control.Label
        SPEditFieldD                   matlab.ui.control.NumericEditField
        LBLabel                        matlab.ui.control.Label
        LBEditFieldD                   matlab.ui.control.NumericEditField
        UBLabel                        matlab.ui.control.Label
        UBEditFieldD                   matlab.ui.control.NumericEditField
        freeCheckBoxD                  matlab.ui.control.CheckBox
        nmaxSpinnerLabel               matlab.ui.control.Label
        nmaxSpinner                    matlab.ui.control.Spinner
        RadialResolutionEditField_2Label  matlab.ui.control.Label
        RadialResolutionEditField      matlab.ui.control.NumericEditField
        C0Label                        matlab.ui.control.Label
        freeCheckBoxC0                 matlab.ui.control.CheckBox
        LBLabel_2                      matlab.ui.control.Label
        LBEditFieldC0                  matlab.ui.control.NumericEditField
        UBLabel_2                      matlab.ui.control.Label
        UBEditFieldC0                  matlab.ui.control.NumericEditField
        SPLabel_2                      matlab.ui.control.Label
        SPEditFieldC0                  matlab.ui.control.NumericEditField
        rLabel                         matlab.ui.control.Label
        freeCheckBoxr                  matlab.ui.control.CheckBox
        LBLabel_3                      matlab.ui.control.Label
        LBEditFieldr                   matlab.ui.control.NumericEditField
        UBLabel_3                      matlab.ui.control.Label
        UBEditFieldr                   matlab.ui.control.NumericEditField
        SPLabel_3                      matlab.ui.control.Label
        SPEditFieldr                   matlab.ui.control.NumericEditField
        RadialLimitEditFieldLabel      matlab.ui.control.Label
        RadialLimitEditField           matlab.ui.control.NumericEditField
        BeamSigmaEditFieldLabel        matlab.ui.control.Label
        BeamSigmaEditField             matlab.ui.control.NumericEditField
        RelevantParametersfordiffusion_moving_beammPanel  matlab.ui.container.Panel
        DLabel_2                       matlab.ui.control.Label
        SPEditFieldLabel               matlab.ui.control.Label
        SPEditFieldD_2                 matlab.ui.control.NumericEditField
        LBEditFieldLabel               matlab.ui.control.Label
        LBEditFieldD_2                 matlab.ui.control.NumericEditField
        UBEditFieldLabel               matlab.ui.control.Label
        UBEditFieldD_2                 matlab.ui.control.NumericEditField
        freeCheckBoxD_2                matlab.ui.control.CheckBox
        C0Label_2                      matlab.ui.control.Label
        freeCheckBoxC0_2               matlab.ui.control.CheckBox
        SPEditFieldLabel_2             matlab.ui.control.Label
        SPEditFieldC0_2                matlab.ui.control.NumericEditField
        LBEditFieldLabel_2             matlab.ui.control.Label
        LBEditFieldC0_2                matlab.ui.control.NumericEditField
        UBEditFieldLabel_2             matlab.ui.control.Label
        UBEditFieldC0_2                matlab.ui.control.NumericEditField
        rLabel_2                       matlab.ui.control.Label
        freeCheckBoxr_2                matlab.ui.control.CheckBox
        SPEditFieldLabel_3             matlab.ui.control.Label
        SPEditFieldr_2                 matlab.ui.control.NumericEditField
        LBEditFieldLabel_3             matlab.ui.control.Label
        LBEditFieldr_2                 matlab.ui.control.NumericEditField
        UBEditFieldLabel_3             matlab.ui.control.Label
        UBEditFieldr_2                 matlab.ui.control.NumericEditField
        dxLabel                        matlab.ui.control.Label
        freeCheckBoxdx                 matlab.ui.control.CheckBox
        SPEditFieldLabel_4             matlab.ui.control.Label
        SPEditFielddx                  matlab.ui.control.NumericEditField
        LBEditFieldLabel_4             matlab.ui.control.Label
        LBEditFielddx                  matlab.ui.control.NumericEditField
        UBEditFieldLabel_4             matlab.ui.control.Label
        UBEditFielddx                  matlab.ui.control.NumericEditField
        nmaxSpinner_2Label             matlab.ui.control.Label
        nmaxSpinner_2                  matlab.ui.control.Spinner
        RadialResolutionEditField_2Label_2  matlab.ui.control.Label
        RadialResolutionEditField_2    matlab.ui.control.NumericEditField
        RadialLimitEditField_2Label    matlab.ui.control.Label
        RadialLimitEditField_2         matlab.ui.control.NumericEditField
        BeamSigmaEditField_2Label      matlab.ui.control.Label
        BeamSigmaEditField_2           matlab.ui.control.NumericEditField
        ClearDataButton                matlab.ui.control.Button
        LoadNewDataButton              matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        data FTIRexperiment % Description
        fittingMethod
    end
    
    methods (Access = private)
        
        function output = displayData(app)
            output = ["You have "+size(app.data.data,2)+" spectra.";...
                app.data.sample;
                "Sample Volume is "+app.data.volume+" μL.";...
                "Spacer Size is "+app.data.pathLength+" μm.";...
                "Time interval is " + app.data.timeInterval+" s.";...
                "Radius is "+string(app.data.radius)+" μm.";...
                "Collected by "+app.data.name+...
                " on "+string(datetime(app.data.dateString,'Format','yyyy-MM-dd'))+"."];
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadNewDataButton
        function LoadNewDataButtonPushed(app, event)
            app.TextArea_2.Value = 'Loading in your data. Please wait.';
            [spectra,freq] = LoadSpectra();
            app.data = FTIRexperiment(spectra,freq(:,1),0,1,0,30,"","","");
            app.CurrentdataTextArea.Value = displayData(app);
            app.TextArea_2.Value = 'Data imported successfully. Fill out the requested parameters below to annotate your data.';
            app.SampleVolumeLEditField.Enable = true;
            app.SpacerSizemEditField.Enable = true;
            app.RadiusmEditField.Enable = true;
            app.TimeIntervalsEditField.Enable = true;
            app.YourNameEditField.Enable = true;
            app.SampleNameEditField.Enable = true;
            app.DateDatePicker.Enable = true;
            app.TodayButton.Enable = true;
        end

        % Button pushed function: ClearDataButton
        function ClearDataButtonPushed(app, event)
            clear app.data
            app.CurrentdataTextArea.Value = "You have no data.";
            cla(app.UIAxes)
            app.UIAxes.XLimMode = 'auto';
            app.UIAxes.YLimMode = 'auto';
            cla(app.UIAxes3)
            app.UIAxes3.XLimMode = 'auto';
            app.UIAxes3.YLimMode = 'auto';
            app.SampleVolumeLEditField.Value = 0;
            app.SampleVolumeLEditField.Enable = false;
            app.SpacerSizemEditField.Value = 0;
            app.SpacerSizemEditField.Enable = false;
            app.TimeIntervalsEditField.Value = 0;
            app.TimeIntervalsEditField.Enable = false;
            app.RadiusmEditField.Value = 0;
            app.RadiusmEditField.Enable = false;
            app.DateDatePicker.Enable = false;
            app.YourNameEditField.Value = "";
            app.YourNameEditField.Enable = false;
            app.SampleNameEditField.Value = "";
            app.SampleNameEditField.Enable = false;
            app.TextArea_2.Value = ['There is no data. Click "Load New Data" to start. ' ...
                'If you have a final spectrum, exclude it for now.'];
            app.SpectrumEditField.Value = 1;
            app.centerEditField.Value = 2339;
            app.wgEditField.Value = 1.9;
            app.wlEditField.Value = 1.7;
            app.a1EditField.Value = 1;
            app.a2EditField.Value = 0.07;
            app.a3EditField.Value = 0;
            app.c0EditField.Value = 0;
            app.c1EditField.Value = 1e-6;
            app.TextArea.Value = "";
            app.DiffusionCoefficientm2sTextArea.Value = "";
            app.nmaxSpinner.Value = 150;
            app.RadialResolutionEditField.Value = 100;
            app.SPEditFieldD.Value = 1000;
            app.LBEditFieldD.Value = 0;
            app.UBEditFieldD.Value = 10000;
            app.TryFitButton.Enable = "off";
            app.TextArea.Value = "";
            app.OtherparametersTextArea.Value = "";
        end

        % Callback function
        function PlotSpectraButtonPushed(app, event)
            cla(app.UIAxes);
            cla(app.UIAxes2);
            
            %find the indicies for the amount of spectra desired
            spectraIndicies = zeros(1,10);
            interval = ceil(size(app.data.data,2)/10);
            for ii = 0:10-1
                spectraIndicies(ii+1) = 1+(ii*interval);
            end
            if spectraIndicies(end) ~= size(app.data.data,2)
                spectraIndicies = [spectraIndicies size(app.data.data,2)];
            end
            
            for ii = spectraIndicies
                temp = app.data.fittedSpectra(ii).fobj;
                pf = co2GasLineFitFunction(app.data.fittedSpectra(ii).x,temp.center,temp.w_g,temp.w_l,...
                    temp.a1,temp.a2,0,0,0);
                plot(app.UIAxes2,app.data.fittedSpectra(ii).x,pf)
                hold(app.UIAxes2,'on')
            end
            hold(app.UIAxes2,'off')
            
            %converts data in f to vector of concentration values of CO2
            %for each spectrum, generates time axis, returns a plot of
            %concentration vs time
            %syntax: plotConcOverTime(f)
            plot(app.UIAxes,timeAxis(app.data),fittedConcOverTime(app.data),'color','blue');
        end

        % Value changed function: SampleVolumeLEditField
        function SampleVolumeLEditFieldValueChanged(app, event)
            app.data.volume = app.SampleVolumeLEditField.Value;
            app.CurrentdataTextArea.Value = displayData(app);
        end

        % Value changed function: SpacerSizemEditField
        function SpacerSizemEditFieldValueChanged(app, event)
            app.data.pathLength = app.SpacerSizemEditField.Value;
            app.CurrentdataTextArea.Value = displayData(app);
        end

        % Value changed function: TimeIntervalsEditField
        function TimeIntervalsEditFieldValueChanged(app, event)
            app.data.timeInterval = app.TimeIntervalsEditField.Value;
            app.CurrentdataTextArea.Value = displayData(app);
        end

        % Button pushed function: TryFitButton
        function TryFitButtonPushed(app, event)
            app.fittingMethod = app.fittingMethod.globalFit;
            app.fittingMethod = app.fittingMethod.calcCurve(app.fittingMethod.pfit);
            
            app.UIAxes.XLimMode = 'auto';
            app.UIAxes.YLimMode = 'auto';
            cla(app.UIAxes)
            plot(app.UIAxes,app.fittingMethod.t,app.fittingMethod.dataMatrix,'o','MarkerSize',4)
            hold(app.UIAxes,'on')
            plot(app.UIAxes,app.fittingMethod.t,app.fittingMethod.simMatrix,'red')
            legend(app.UIAxes,"Data points","Fitted Curve","Location","northwest")
            hold(app.UIAxes,'off')
            
            
            % get confidence intervals
%             ci = confint(out.fobj);
%             stdev = (ci(2)-out.fobj.D)/2;
%             pct = stdev/out.fobj.D;
            D_idx = find(app.fittingMethod.freeFitParamNames == "D",1);
            app.DiffusionCoefficientm2sTextArea.Value = string(app.fittingMethod.pfit(D_idx));
            for i = 1:numel(app.fittingMethod.freeFitParamNames)
                if i ~= D_idx
                    app.OtherparametersTextArea.Value = app.OtherparametersTextArea.Value + ...
                        app.fittingMethod.freeFitParamNames(i) + " = " + string(app.fittingMethod.pfit(i));
                end
            end
            app.TryFitButton.Enable = "off";
            app.TestGuessButton.Enable = 'off';
        end

        % Callback function
        function SaturatedConcButtonGroupSelectionChange(app, event)
            selectedButton = app.SaturatedConcButtonGroup.SelectedObject;
            app.CustomFinalConcEditField.Enable = 'off';
            app.CustomFinalConcEditField.Value = 0;
            if selectedButton.Text == "Use Last Spectrum"
                app.data.finalSpectrum = app.data.data(:,end);
                app.data = app.data.getFinalConc;
            elseif selectedButton.Text == "Get Final Spectrum"
                app.data.finalSpectrum = LoadSpectra();
                app.data = app.data.getFinalConc;
            elseif selectedButton.Text == "Input Custom"
                app.data.finalSpectrum = [];
                app.CustomFinalConcEditField.Enable = 'on';
                app.data.finalConc = 0;
            end
            app.FinalConcTextArea.Value = string(app.data.finalConc)+" M";
        end

        % Value changed function: BeamSigmaEditField, 
        % BeamSigmaEditField_2, LBEditFieldC0, LBEditFieldC0_2, 
        % LBEditFieldD, LBEditFieldD_2, LBEditFielddx, 
        % LBEditFieldr, RadialLimitEditField, 
        % RadialLimitEditField_2, RadialResolutionEditField, 
        % RadialResolutionEditField_2, SPEditFieldC0, 
        % SPEditFieldC0_2, SPEditFieldD, SPEditFieldD_2, 
        % SPEditFielddx, SPEditFieldr, UBEditFieldC0, 
        % UBEditFieldC0_2, UBEditFieldD, UBEditFieldD_2, 
        % UBEditFielddx, UBEditFieldr, freeCheckBoxC0, 
        % freeCheckBoxC0_2, freeCheckBoxD, freeCheckBoxD_2, 
        % freeCheckBoxdx, freeCheckBoxr, nmaxSpinner, nmaxSpinner_2
        function FitParameterChanged(app, event)
            FitMethodDropDownValueChanged(app);
            app.TestGuessButton.Enable = 'on';
            app.TryFitButton.Enable = 'on';
        end

        % Button pushed function: TestParametersButton
        function TestParametersButtonPushed(app, event)
            % have the user select which spectrum to guess from
            ii = app.SpectrumEditField.Value;
            
            % set the fit range
            range1 = [2290 2390];
            
            % set starting point using values from the user
            center = app.centerEditField.Value;
            wg = app.wgEditField.Value;
            wl = app.wlEditField.Value;
            a1 = app.a1EditField.Value;
            a2 = app.a2EditField.Value; % expected Boltzmann factor for bend
            a3 = app.a3EditField.Value; % gas lines
            c0 = app.c0EditField.Value; % baseline offset
            c1 = app.c1EditField.Value; % baseline slope
            
            %fit function requires fliipped inputs
            freq = flip(app.data.freqAxis);
            s = flip(app.data.data(:,ii));
            
            %get x and y data for the fit
            ind1 = find(freq>=range1(1) & freq<range1(2));
            x = freq(ind1);
            ydata = s(ind1);
            
            %plot the fitted function using user parameters
            yfit = co2GasLineFitFunction(x,center,wg,wl,a1,a2,a3,c0,c1);
            res = ydata-yfit;
            sse = sum(res.^2);
            
            cla(app.UIAxes3)
            plot(app.UIAxes3,x,ydata,'o',x,yfit,x,res-0.1,'r-o')
            %app.UIAxes3.Title = (sprintf('Initial guess SSE = %f',sse));
        end

        % Button pushed function: DoFitButton
        function DoFitButtonPushed(app, event)
            app.TextArea.Value = "Running...";
            T = tic; %time the fitting for later display
            app.data = gasLineFit(app.data,app.centerEditField.Value,app.wgEditField.Value,app.wlEditField.Value,...
                app.a1EditField.Value,app.a2EditField.Value,app.a3EditField.Value,app.c0EditField.Value,...
                app.c1EditField.Value);
            stop = toc(T);
            
            %select 4 evenly placed fits to plot
            n_spectra = size(app.data.data,2);
            %iis = ceil([1 n_spectra/4 n_spectra*3/4 n_spectra]);
            % select ALL the spectra to plot
            iis = 1:n_spectra;
            cla(app.UIAxes3)
            for ii = iis
                plot(app.UIAxes3,app.data.fittedSpectra(ii).x,app.data.fittedSpectra(ii).ydata,'o',...
                    app.data.fittedSpectra(ii).x,app.data.fittedSpectra(ii).yfit,...
                    app.data.fittedSpectra(ii).x,app.data.fittedSpectra(ii).res-0.1,'ro')
                hold(app.UIAxes3,'on')
            end
            hold(app.UIAxes3,'off')
            
            %let the user know how it went
            app.TextArea.Value = "";
            tl = 0;
            for ii = 1:n_spectra
                if app.data.fittedSpectra(ii).O.exitflag < 1
                    app.TextArea.Value = [app.TextAreaValue.Value;'Spectrum '+ii+' did not converge!!! Results might not be trustworthy.'];
                    tl = tl+1;
                end
            end
            if tl==0
                app.TextArea.Value = "All fits were successful.";
            end
            app.TextArea.Value = [app.TextArea.Value;"Fitting took "+stop+" seconds."];
        end

        % Button pushed function: TestGuessButton
        function TestGuessButtonPushed(app, event)
            cla(app.UIAxes)
            ydata = app.fittingMethod.dataMatrix;
            x = app.fittingMethod.t;
            app.fittingMethod = app.fittingMethod.calcCurve(freeFitParamValues(app.fittingMethod));
            y = app.fittingMethod.simMatrix;
            res = ydata(:)-y(:);
            plot(app.UIAxes,x,ydata,'o','MarkerSize',4)
            hold(app.UIAxes,'on')
            plot(app.UIAxes,x,y)
            plot(app.UIAxes,x,res-0.1,'ro','MarkerSize',4)
            hold(app.UIAxes,'off')
            app.TestGuessButton.Enable = 'off';
        end

        % Value changed function: RadiusmEditField
        function RadiusmEditFieldValueChanged(app, event)
            value = app.RadiusmEditField.Value;
            app.data.radius = value;
            app.CurrentdataTextArea.Value = displayData(app);
        end

        % Callback function
        function CustomFinalConcEditFieldValueChanged(app, event)
            value = app.CustomFinalConcEditField.Value;
            app.data.finalConc = value;
            app.FinalConcTextArea.Value = string(app.data.finalConc)+" M";
            FitParameterChanged(app)
        end

        % Value changed function: DateDatePicker
        function DateDatePickerValueChanged(app, event)
            value = app.DateDatePicker.Value;
            app.data.dateString = value;
            app.CurrentdataTextArea.Value = displayData(app);
        end

        % Button pushed function: TodayButton
        function TodayButtonPushed(app, event)
            app.DateDatePicker.Value = datetime(date);
            app.DateDatePickerValueChanged
        end

        % Value changed function: YourNameEditField
        function YourNameEditFieldValueChanged(app, event)
            value = app.YourNameEditField.Value;
            app.data.name = value;
            app.CurrentdataTextArea.Value = displayData(app);
        end

        % Value changed function: SampleNameEditField
        function SampleNameEditFieldValueChanged(app, event)
            value = app.SampleNameEditField.Value;
            app.data.sample = value;
            app.CurrentdataTextArea.Value = displayData(app);
        end

        % Button pushed function: GenerateTimeAxisButton
        function GenerateTimeAxisButtonPushed(app, event)
            app.data = app.data.timeAxis;
            app.FitMethodDropDown.Enable = "on";
        end

        % Value changed function: FitMethodDropDown
        function FitMethodDropDownValueChanged(app, event)
            value = app.FitMethodDropDown.Value;
            app.RelevantParametersfordiffusionmPanel.Visible = 'off';
            app.RelevantParametersfordiffusionmPanel.Enable = 'off';
            app.RelevantParametersfordiffusion_moving_beammPanel.Visible = 'off';
            app.RelevantParametersfordiffusion_moving_beammPanel.Enable = 'off';
            switch value
                case 'diffusion.m'
                    app.RelevantParametersfordiffusionmPanel.Visible = 'on';
                    app.RelevantParametersfordiffusionmPanel.Enable = 'on';
                    
                    if app.freeCheckBoxr.Value
                    options.gel_radius = fitParamBnd('gel_radius', ...
                        app.SPEditFieldr.Value, ...
                        app.LBEditFieldr.Value,app.UBEditFieldr.Value, ...
                        'free');
                    else
                    options.gel_radius = fitParamBnd('gel_radius', ...
                        app.SPEditFieldr.Value, ...
                        app.LBEditFieldr.Value,app.UBEditFieldr.Value, ...
                        'fixed');    
                    end
                    if app.freeCheckBoxC0.Value
                    options.finalConc = fitParamBnd( ...
                        'finalConc',app.SPEditFieldC0.Value, ...
                        app.LBEditFieldC0.Value,app.UBEditFieldC0.Value, ...
                        'free');
                    else
                    options.finalConc = fitParamBnd( ...
                        'finalConc',app.SPEditFieldC0.Value, ...
                        app.LBEditFieldC0.Value,app.UBEditFieldC0.Value, ...
                        'fixed');
                    end
                    if app.freeCheckBoxD.Value
                    options.D = fitParamBnd('D',app.SPEditFieldD.Value, ...
                        app.LBEditFieldD.Value,app.UBEditFieldD.Value, ...
                        'free');
                    else
                    options.D = fitParamBnd('D',app.SPEditFieldD.Value, ...
                        app.LBEditFieldD.Value,app.UBEditFieldD.Value, ...
                        'fixed');
                    end
                    options.nmax = app.nmaxSpinner.Value;
                    options.rres = app.RadialResolutionEditField.Value;
                    options.rlim = app.RadialLimitEditField.Value;
                    options.beam_size = app.BeamSigmaEditField.Value;
                    options.t = app.data.timePts;
                    options.dataMatrix = app.data.concOverTime;
                    options.ub = options.D.ub;
                    options.lb = options.D.lb;
                    app.fittingMethod = constantDMdl(options);
                    app.fittingMethod.lb = freeFitParamLowerBounds(app.fittingMethod);
                    app.fittingMethod.ub = freeFitParamUpperBounds(app.fittingMethod);
                    
                case 'diffusion_moving_beam.m'
                    app.RelevantParametersfordiffusion_moving_beammPanel.Visible = 'on';
                    app.RelevantParametersfordiffusion_moving_beammPanel.Enable = 'on';
                    
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CurrentdataLabel = uilabel(app.UIFigure);
            app.CurrentdataLabel.Text = 'Current data:';
            app.CurrentdataTextArea = uitextarea(app.UIFigure);
            app.CurrentdataTextArea.Value = {'You have no data.'};
            app.DiffusionCoefficientm2sLabel = uilabel(app.UIFigure);
            app.DiffusionCoefficientm2sLabel.Text = 'Diffusion Coefficient (μm^2/s)';
            app.DiffusionCoefficientm2sTextArea = uitextarea(app.UIFigure);
            app.OtherparametersTextAreaLabel = uilabel(app.UIFigure);
            app.OtherparametersTextAreaLabel.Text = 'Other parameters';
            app.OtherparametersTextArea = uitextarea(app.UIFigure);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.FormatDataTab = uitab(app.TabGroup);
            app.FormatDataTab.Title = 'Format Data';
            app.TextArea_2 = uitextarea(app.FormatDataTab);
            app.TextArea_2.Value = {'There is no data. Click "Load New Data" to start. If you have a final spectrum, exclude it for now.'};
            app.SampleVolumeLEditField_2Label_2 = uilabel(app.FormatDataTab);
            app.SampleVolumeLEditField_2Label_2.Text = 'Sample Volume (μL)';
            app.SampleVolumeLEditField = uieditfield(app.FormatDataTab, 'numeric');
            app.SampleVolumeLEditField.ValueChangedFcn = createCallbackFcn(app, @SampleVolumeLEditFieldValueChanged, true);
            app.SampleVolumeLEditField.Enable = 'off';
            app.SpacerSizemEditField_2Label_2 = uilabel(app.FormatDataTab);
            app.SpacerSizemEditField_2Label_2.Text = 'Spacer Size (μm)';
            app.SpacerSizemEditField = uieditfield(app.FormatDataTab, 'numeric');
            app.SpacerSizemEditField.ValueChangedFcn = createCallbackFcn(app, @SpacerSizemEditFieldValueChanged, true);
            app.SpacerSizemEditField.Enable = 'off';
            app.TimeIntervalsEditField_2Label_2 = uilabel(app.FormatDataTab);
            app.TimeIntervalsEditField_2Label_2.Text = 'Time Interval (s)';
            app.TimeIntervalsEditField = uieditfield(app.FormatDataTab, 'numeric');
            app.TimeIntervalsEditField.ValueChangedFcn = createCallbackFcn(app, @TimeIntervalsEditFieldValueChanged, true);
            app.TimeIntervalsEditField.Enable = 'off';
            app.RadiusmEditField_2Label = uilabel(app.FormatDataTab);
            app.RadiusmEditField_2Label.Text = 'Radius (μm)';
            app.RadiusmEditField = uieditfield(app.FormatDataTab, 'numeric');
            app.RadiusmEditField.ValueChangedFcn = createCallbackFcn(app, @RadiusmEditFieldValueChanged, true);
            app.RadiusmEditField.Enable = 'off';
            app.DateDatePickerLabel = uilabel(app.FormatDataTab);
            app.DateDatePickerLabel.Text = 'Date';
            app.DateDatePicker = uidatepicker(app.FormatDataTab);
            app.DateDatePicker.ValueChangedFcn = createCallbackFcn(app, @DateDatePickerValueChanged, true);
            app.DateDatePicker.Enable = 'off';
            app.TodayButton = uibutton(app.FormatDataTab, 'push');
            app.TodayButton.ButtonPushedFcn = createCallbackFcn(app, @TodayButtonPushed, true);
            app.TodayButton.Enable = 'off';
            app.TodayButton.Text = 'Today?';
            app.YourNameEditFieldLabel = uilabel(app.FormatDataTab);
            app.YourNameEditFieldLabel.Text = 'Your Name';
            app.YourNameEditField = uieditfield(app.FormatDataTab, 'text');
            app.YourNameEditField.ValueChangedFcn = createCallbackFcn(app, @YourNameEditFieldValueChanged, true);
            app.YourNameEditField.Enable = 'off';
            app.SampleNameEditFieldLabel = uilabel(app.FormatDataTab);
            app.SampleNameEditFieldLabel.Text = 'Sample Name';
            app.SampleNameEditField = uieditfield(app.FormatDataTab, 'text');
            app.SampleNameEditField.ValueChangedFcn = createCallbackFcn(app, @SampleNameEditFieldValueChanged, true);
            app.SampleNameEditField.Enable = 'off';
            app.GasLineFitTab = uitab(app.TabGroup);
            app.GasLineFitTab.Title = 'Gas Line Fit';
            app.SpectrumEditFieldLabel = uilabel(app.GasLineFitTab);
            app.SpectrumEditFieldLabel.Text = 'Spectrum #';
            app.SpectrumEditField = uieditfield(app.GasLineFitTab, 'numeric');
            app.SpectrumEditField.Value = 1;
            app.centerEditFieldLabel = uilabel(app.GasLineFitTab);
            app.centerEditFieldLabel.Text = 'center';
            app.centerEditField = uieditfield(app.GasLineFitTab, 'numeric');
            app.centerEditField.ValueDisplayFormat = '%.1f';
            app.centerEditField.Value = 2339;
            app.wgEditFieldLabel = uilabel(app.GasLineFitTab);
            app.wgEditFieldLabel.Text = 'wg';
            app.wgEditField = uieditfield(app.GasLineFitTab, 'numeric');
            app.wgEditField.Value = 1.9;
            app.wlEditFieldLabel = uilabel(app.GasLineFitTab);
            app.wlEditFieldLabel.Text = 'wl';
            app.wlEditField = uieditfield(app.GasLineFitTab, 'numeric');
            app.wlEditField.Value = 1.7;
            app.a1EditFieldLabel = uilabel(app.GasLineFitTab);
            app.a1EditFieldLabel.Text = 'a1';
            app.a1EditField = uieditfield(app.GasLineFitTab, 'numeric');
            app.a1EditField.Value = 1;
            app.a2EditFieldLabel = uilabel(app.GasLineFitTab);
            app.a2EditFieldLabel.Text = 'a2';
            app.a2EditField = uieditfield(app.GasLineFitTab, 'numeric');
            app.a2EditField.Value = 0.07;
            app.a3EditFieldLabel = uilabel(app.GasLineFitTab);
            app.a3EditFieldLabel.Text = 'a3';
            app.a3EditField = uieditfield(app.GasLineFitTab, 'numeric');
            app.c0EditFieldLabel = uilabel(app.GasLineFitTab);
            app.c0EditFieldLabel.Text = 'c0';
            app.c0EditField = uieditfield(app.GasLineFitTab, 'numeric');
            app.c1EditFieldLabel = uilabel(app.GasLineFitTab);
            app.c1EditFieldLabel.Text = 'c1';
            app.c1EditField = uieditfield(app.GasLineFitTab, 'numeric');
            app.c1EditField.Value = 1e-06;
            app.InitalFitGuessLabel = uilabel(app.GasLineFitTab);
            app.InitalFitGuessLabel.Text = 'Inital Fit Guess';
            app.TestParametersButton = uibutton(app.GasLineFitTab, 'push');
            app.TestParametersButton.ButtonPushedFcn = createCallbackFcn(app, @TestParametersButtonPushed, true);
            app.TestParametersButton.Text = 'Test Parameters';
            app.DoFitButton = uibutton(app.GasLineFitTab, 'push');
            app.DoFitButton.ButtonPushedFcn = createCallbackFcn(app, @DoFitButtonPushed, true);
            app.DoFitButton.Text = 'Do Fit';
            app.TextArea = uitextarea(app.GasLineFitTab);
            app.UIAxes3 = uiaxes(app.GasLineFitTab);
            title(app.UIAxes3, 'FTIR Spectrum of the CO2 Peak')
            xlabel(app.UIAxes3, 'frequency (cm^{-1})')
            ylabel(app.UIAxes3, 'absorbance (AU)')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes3.XLim = [2290 2390];
            app.UIAxes3.YTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.CurveFittingTab = uitab(app.TabGroup);
            app.CurveFittingTab.Title = 'Curve Fitting';
            app.TryFitButton = uibutton(app.CurveFittingTab, 'push');
            app.TryFitButton.ButtonPushedFcn = createCallbackFcn(app, @TryFitButtonPushed, true);
            app.TryFitButton.Enable = 'off';
            app.TryFitButton.Text = 'Try Fit';
            app.TestGuessButton = uibutton(app.CurveFittingTab, 'push');
            app.TestGuessButton.ButtonPushedFcn = createCallbackFcn(app, @TestGuessButtonPushed, true);
            app.TestGuessButton.Enable = 'off';
            app.TestGuessButton.Text = 'Test Guess';
            app.GenerateTimeAxisButton = uibutton(app.CurveFittingTab, 'push');
            app.GenerateTimeAxisButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateTimeAxisButtonPushed, true);
            app.GenerateTimeAxisButton.Text = 'Generate Time Axis';
            app.FitMethodDropDownLabel = uilabel(app.CurveFittingTab);
            app.FitMethodDropDownLabel.Text = 'Fit Method';
            app.FitMethodDropDown = uidropdown(app.CurveFittingTab);
            app.FitMethodDropDown.Items = {'Select a fit method', 'diffusion.m', 'diffusion_moving_beam.m', 'Option 4'};
            app.FitMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @FitMethodDropDownValueChanged, true);
            app.FitMethodDropDown.Enable = 'off';
            app.FitMethodDropDown.Value = 'Select a fit method';
            app.RelevantParametersfordiffusionmPanel = uipanel(app.CurveFittingTab);
            app.RelevantParametersfordiffusionmPanel.Title = 'Relevant Parameters for diffusion.m';
            app.DLabel = uilabel(app.RelevantParametersfordiffusionmPanel);
            app.DLabel.Text = 'D';
            app.SPLabel = uilabel(app.RelevantParametersfordiffusionmPanel);
            app.SPLabel.Text = 'SP';
            app.SPEditFieldD = uieditfield(app.RelevantParametersfordiffusionmPanel, 'numeric');
            app.SPEditFieldD.ValueChangedFcn = createCallbackFcn(app, @FitParameterChanged, true);
            app.SPEditFieldD.Value = 1000;
            app.LBLabel = uilabel(app.RelevantParametersfordiffusionmPanel);
            app.LBLabel.Text = 'LB';
            app.LBEditFieldD = uieditfield(app.RelevantParametersfordiffusionmPanel, 'numeric');
            app.LBEditFieldD.Limits = [0 Inf];
            app.LBEditFieldD.ValueChangedFcn = createCallbackFcn(app, @FitParameterChanged, true);
            app.UBLabel = uilabel(app.RelevantParametersfordiffusionmPanel);
            app.UBLabel.Text = 'UB';
            app.UBEditFieldD = uieditfield(app.RelevantParametersfordiffusionmPanel, 'numeric');
            app.UBEditFieldD.Limits = [0 Inf];
            app.UBEditFieldD.ValueChangedFcn = createCallbackFcn(app, @FitParameterChanged, true);
            app.UBEditFieldD.Value = 10000;
            app.freeCheckBoxD = uicheckbox(app.RelevantParametersfordiffusionmPanel);
            app.freeCheckBoxD.ValueChangedFcn = createCallbackFcn(app, @FitParameterChanged, true);
            app.freeCheckBoxD.Text = 'free?';
            app.nmaxSpinnerLabel = uilabel(app.RelevantParametersfordiffusionmPanel);
            app.nmaxSpinnerLabel.Text = 'nmax';
            app.nmaxSpinner = uispinner(app.RelevantParametersfordiffusionmPanel);
            app.nmaxSpinner.Limits = [0 1000];
            app.nmaxSpinner.ValueChangedFcn = createCallbackFcn(app, @FitParameterChanged, true);
            app.nmaxSpinner.Value = 150;
            app.RadialResolutionEditField_2Label = uilabel(app.RelevantParametersfordiffusionmPanel);
            app.RadialResolutionEditField_2Label.Text = 'Radial Resolution';
            app.RadialResolutionEditField = uieditfield(app.RelevantParametersfordiffusionmPanel, 'numeric');
            app.RadialResolutionEditField.ValueChangedFcn = createCallbackFcn(app, @FitParameterChanged, true);
            app.RadialResolutionEditField.Value = 100;
            app.C0Label = uilabel(app.RelevantParametersfordiffusionmPanel);
            app.C0Label.Text = 'C0';
            app.freeCheckBoxC0 = uicheckbox(app.RelevantParametersfordiffusionmPanel);
            app.freeCheckBoxC0.ValueChangedFcn = createCallbackFcn(app, @FitParameterChanged, true);
            app.freeCheckBoxC0.Text = 'free?';
            app.LBLabel_2 = uilabel(app.RelevantParametersfordiffusionmPanel);
            app.LBLabel_2.Text = 'LB';
            app.LBEditFieldC0 = uieditfield(app.RelevantParametersfordiffusionmPanel, 'numeric');
            app.LBEditFieldC0.Limits = [0 Inf];
            app.LBEditFieldC0.ValueChangedFcn = createCallbackFcn(app, @FitParameterChanged, true);
            app.UBLabel_2 = uilabel(app.RelevantParametersfordiffusionmPanel);
            app.UBLabel_2.Text = 'UB';
            app.UBEditFieldC0 = uieditfield(app.RelevantParametersfordiffusionmPanel, 'numeric');
            app.UBEditFieldC0.Limits = [0 Inf];
            app.UBEditFieldC0.ValueChangedFcn = createCallbackFcn(app, @FitParameterChanged, true);
            app.UBEditFieldC0.Value = 10000;
            app.SPLabel_2 = uilabel(app.RelevantParametersfordiffusionmPanel);
            app.SPLabel_2.Text = 'SP';
            app.SPEditFieldC0 = uieditfield(app.RelevantParametersfordiffusionmPanel, 'numeric');
            app.SPEditFieldC0.ValueChangedFcn = createCallbackFcn(app, @FitParameterChanged, true);
            app.SPEditFieldC0.Value = 1000;
            app.rLabel = uilabel(app.RelevantParametersfordiffusionmPanel);
            app.rLabel.Text = 'r';
            app.freeCheckBoxr = uicheckbox(app.RelevantParametersfordiffusionmPanel);
            app.freeCheckBoxr.ValueChangedFcn = createCallbackFcn(app, @FitParameterChanged, true);
            app.freeCheckBoxr.Text = 'free?';
            app.LBLabel_3 = uilabel(app.RelevantParametersfordiffusionmPanel);
            app.LBLabel_3.Text = 'LB';
            app.LBEditFieldr = uieditfield(app.RelevantParametersfordiffusionmPanel, 'numeric');
            app.LBEditFieldr.Limits = [0 Inf];
            app.LBEditFieldr.ValueChangedFcn = createCallbackFcn(app, @FitParameterChanged, true);
            app.UBLabel_3 = uilabel(app.RelevantParametersfordiffusionmPanel);
            app.UBLabel_3.Text = 'UB';
            app.UBEditFieldr = uieditfield(app.RelevantParametersfordiffusionmPanel, 'numeric');
            app.UBEditFieldr.Limits = [0 Inf];
            app.UBEditFieldr.ValueChangedFcn = createCallbackFcn(app, @FitParameterChanged, true);
            app.UBEditFieldr.Value = 10000;
            app.SPLabel_3 = uilabel(app.RelevantParametersfordiffusionmPanel);
            app.SPLabel_3.Text = 'SP';
            app.SPEditFieldr = uieditfield(app.RelevantParametersfordiffusionmPanel, 'numeric');
            app.SPEditFieldr.ValueChangedFcn = createCallbackFcn(app, @FitParameterChanged, true);
            app.SPEditFieldr.Value = 1000;
            app.RadialLimitEditFieldLabel = uilabel(app.RelevantParametersfordiffusionmPanel);
            app.RadialLimitEditFieldLabel.Text = 'Radial Limit';
            app.RadialLimitEditField = uieditfield(app.RelevantParametersfordiffusionmPanel, 'numeric');
            app.RadialLimitEditField.ValueChangedFcn = createCallbackFcn(app, @FitParameterChanged, true);
            app.RadialLimitEditField.Value = 3000;
            app.BeamSigmaEditFieldLabel = uilabel(app.RelevantParametersfordiffusionmPanel);
            app.BeamSigmaEditFieldLabel.Text = 'Beam Sigma';
            app.BeamSigmaEditField = uieditfield(app.RelevantParametersfordiffusionmPanel, 'numeric');
            app.BeamSigmaEditField.ValueChangedFcn = createCallbackFcn(app, @FitParameterChanged, true);
            app.BeamSigmaEditField.Value = 704;
            app.RelevantParametersfordiffusion_moving_beammPanel = uipanel(app.CurveFittingTab);
            app.RelevantParametersfordiffusion_moving_beammPanel.Title = 'Relevant Parameters for diffusion_moving_beam.m';
            app.DLabel_2 = uilabel(app.RelevantParametersfordiffusion_moving_beammPanel);
            app.DLabel_2.Text = 'D';
            app.SPEditFieldLabel = uilabel(app.RelevantParametersfordiffusion_moving_beammPanel);
            app.SPEditFieldLabel.Text = 'SP';
            app.SPEditFieldD_2 = uieditfield(app.RelevantParametersfordiffusion_moving_beammPanel, 'numeric');
            app.SPEditFieldD_2.ValueChangedFcn = createCallbackFcn(app, @FitParameterChanged, true);
            app.LBEditFieldLabel = uilabel(app.RelevantParametersfordiffusion_moving_beammPanel);
            app.LBEditFieldLabel.Text = 'LB';
            app.LBEditFieldD_2 = uieditfield(app.RelevantParametersfordiffusion_moving_beammPanel, 'numeric');
            app.LBEditFieldD_2.ValueChangedFcn = createCallbackFcn(app, @FitParameterChanged, true);
            app.UBEditFieldLabel = uilabel(app.RelevantParametersfordiffusion_moving_beammPanel);
            app.UBEditFieldLabel.Text = 'UB';
            app.UBEditFieldD_2 = uieditfield(app.RelevantParametersfordiffusion_moving_beammPanel, 'numeric');
            app.UBEditFieldD_2.ValueChangedFcn = createCallbackFcn(app, @FitParameterChanged, true);
            app.freeCheckBoxD_2 = uicheckbox(app.RelevantParametersfordiffusion_moving_beammPanel);
            app.freeCheckBoxD_2.ValueChangedFcn = createCallbackFcn(app, @FitParameterChanged, true);
            app.freeCheckBoxD_2.Text = 'free?';
            app.C0Label_2 = uilabel(app.RelevantParametersfordiffusion_moving_beammPanel);
            app.C0Label_2.Text = 'C0';
            app.freeCheckBoxC0_2 = uicheckbox(app.RelevantParametersfordiffusion_moving_beammPanel);
            app.freeCheckBoxC0_2.ValueChangedFcn = createCallbackFcn(app, @FitParameterChanged, true);
            app.freeCheckBoxC0_2.Text = 'free?';
            app.SPEditFieldLabel_2 = uilabel(app.RelevantParametersfordiffusion_moving_beammPanel);
            app.SPEditFieldLabel_2.Text = 'SP';
            app.SPEditFieldC0_2 = uieditfield(app.RelevantParametersfordiffusion_moving_beammPanel, 'numeric');
            app.SPEditFieldC0_2.ValueChangedFcn = createCallbackFcn(app, @FitParameterChanged, true);
            app.LBEditFieldLabel_2 = uilabel(app.RelevantParametersfordiffusion_moving_beammPanel);
            app.LBEditFieldLabel_2.Text = 'LB';
            app.LBEditFieldC0_2 = uieditfield(app.RelevantParametersfordiffusion_moving_beammPanel, 'numeric');
            app.LBEditFieldC0_2.ValueChangedFcn = createCallbackFcn(app, @FitParameterChanged, true);
            app.UBEditFieldLabel_2 = uilabel(app.RelevantParametersfordiffusion_moving_beammPanel);
            app.UBEditFieldLabel_2.Text = 'UB';
            app.UBEditFieldC0_2 = uieditfield(app.RelevantParametersfordiffusion_moving_beammPanel, 'numeric');
            app.UBEditFieldC0_2.ValueChangedFcn = createCallbackFcn(app, @FitParameterChanged, true);
            app.rLabel_2 = uilabel(app.RelevantParametersfordiffusion_moving_beammPanel);
            app.rLabel_2.Text = 'r';
            app.freeCheckBoxr_2 = uicheckbox(app.RelevantParametersfordiffusion_moving_beammPanel);
            app.freeCheckBoxr_2.Text = 'free?';
            app.SPEditFieldLabel_3 = uilabel(app.RelevantParametersfordiffusion_moving_beammPanel);
            app.SPEditFieldLabel_3.Text = 'SP';
            app.SPEditFieldr_2 = uieditfield(app.RelevantParametersfordiffusion_moving_beammPanel, 'numeric');
            app.LBEditFieldLabel_3 = uilabel(app.RelevantParametersfordiffusion_moving_beammPanel);
            app.LBEditFieldLabel_3.Text = 'LB';
            app.LBEditFieldr_2 = uieditfield(app.RelevantParametersfordiffusion_moving_beammPanel, 'numeric');
            app.UBEditFieldLabel_3 = uilabel(app.RelevantParametersfordiffusion_moving_beammPanel);
            app.UBEditFieldLabel_3.Text = 'UB';
            app.UBEditFieldr_2 = uieditfield(app.RelevantParametersfordiffusion_moving_beammPanel, 'numeric');
            app.dxLabel = uilabel(app.RelevantParametersfordiffusion_moving_beammPanel);
            app.dxLabel.Text = 'dx';
            app.freeCheckBoxdx = uicheckbox(app.RelevantParametersfordiffusion_moving_beammPanel);
            app.freeCheckBoxdx.ValueChangedFcn = createCallbackFcn(app, @FitParameterChanged, true);
            app.freeCheckBoxdx.Text = 'free?';
            app.SPEditFieldLabel_4 = uilabel(app.RelevantParametersfordiffusion_moving_beammPanel);
            app.SPEditFieldLabel_4.Text = 'SP';
            app.SPEditFielddx = uieditfield(app.RelevantParametersfordiffusion_moving_beammPanel, 'numeric');
            app.SPEditFielddx.ValueChangedFcn = createCallbackFcn(app, @FitParameterChanged, true);
            app.LBEditFieldLabel_4 = uilabel(app.RelevantParametersfordiffusion_moving_beammPanel);
            app.LBEditFieldLabel_4.Text = 'LB';
            app.LBEditFielddx = uieditfield(app.RelevantParametersfordiffusion_moving_beammPanel, 'numeric');
            app.LBEditFielddx.ValueChangedFcn = createCallbackFcn(app, @FitParameterChanged, true);
            app.UBEditFieldLabel_4 = uilabel(app.RelevantParametersfordiffusion_moving_beammPanel);
            app.UBEditFieldLabel_4.Text = 'UB';
            app.UBEditFielddx = uieditfield(app.RelevantParametersfordiffusion_moving_beammPanel, 'numeric');
            app.UBEditFielddx.ValueChangedFcn = createCallbackFcn(app, @FitParameterChanged, true);
            app.nmaxSpinner_2Label = uilabel(app.RelevantParametersfordiffusion_moving_beammPanel);
            app.nmaxSpinner_2Label.Text = 'nmax';
            app.nmaxSpinner_2 = uispinner(app.RelevantParametersfordiffusion_moving_beammPanel);
            app.nmaxSpinner_2.Limits = [0 1000];
            app.nmaxSpinner_2.ValueChangedFcn = createCallbackFcn(app, @FitParameterChanged, true);
            app.nmaxSpinner_2.Value = 150;
            app.RadialResolutionEditField_2Label_2 = uilabel(app.RelevantParametersfordiffusion_moving_beammPanel);
            app.RadialResolutionEditField_2Label_2.Text = 'Radial Resolution';
            app.RadialResolutionEditField_2 = uieditfield(app.RelevantParametersfordiffusion_moving_beammPanel, 'numeric');
            app.RadialResolutionEditField_2.ValueChangedFcn = createCallbackFcn(app, @FitParameterChanged, true);
            app.RadialResolutionEditField_2.Value = 100;
            app.RadialLimitEditField_2Label = uilabel(app.RelevantParametersfordiffusion_moving_beammPanel);
            app.RadialLimitEditField_2Label.Text = 'Radial Limit';
            app.RadialLimitEditField_2 = uieditfield(app.RelevantParametersfordiffusion_moving_beammPanel, 'numeric');
            app.RadialLimitEditField_2.ValueChangedFcn = createCallbackFcn(app, @FitParameterChanged, true);
            app.RadialLimitEditField_2.Value = 3000;
            app.BeamSigmaEditField_2Label = uilabel(app.RelevantParametersfordiffusion_moving_beammPanel);
            app.BeamSigmaEditField_2Label.Text = 'Beam Sigma';
            app.BeamSigmaEditField_2 = uieditfield(app.RelevantParametersfordiffusion_moving_beammPanel, 'numeric');
            app.BeamSigmaEditField_2.ValueChangedFcn = createCallbackFcn(app, @FitParameterChanged, true);
            app.BeamSigmaEditField_2.Value = 704;
            app.ClearDataButton = uibutton(app.UIFigure, 'push');
            app.ClearDataButton.ButtonPushedFcn = createCallbackFcn(app, @ClearDataButtonPushed, true);
            app.ClearDataButton.Text = 'Clear Data';
            app.LoadNewDataButton = uibutton(app.UIFigure, 'push');
            app.LoadNewDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadNewDataButtonPushed, true);
            app.LoadNewDataButton.Text = 'Load New Data';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Concentration of CO2 vs Time')
            xlabel(app.UIAxes, 'time (s)')
            ylabel(app.UIAxes, 'concentration (M)')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FTIR_data_analysis

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end